classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.Button
        TourButton              matlab.ui.control.Button
        roof                    matlab.ui.control.Image
        HomeFlowNavigatorLabel  matlab.ui.control.Label
        secretmessageeLabel     matlab.ui.control.Label
        HomeBackgroud           matlab.ui.control.Button
        tourPanel               matlab.ui.container.Panel
        BackButton              matlab.ui.control.Button
        imageAxes               matlab.ui.control.UIAxes
        tvchannel               matlab.ui.control.UIAxes
        return2home             matlab.ui.control.Button
        KitchenButton           matlab.ui.control.Button
        BathroomButton          matlab.ui.control.Button
        HallwayButton           matlab.ui.control.Button
        LivingroomButton        matlab.ui.control.Button
        Bedroombutton           matlab.ui.control.Button
        PickaroomyouwishtonavigateLabel  matlab.ui.control.Label
        house_layout            matlab.ui.control.Image
        background2             matlab.ui.control.Image
        tvlabel                 matlab.ui.control.Label
        heatinglabel            matlab.ui.control.Label
        lamplabel               matlab.ui.control.Label
        lightlabel              matlab.ui.control.Label
        return1                 matlab.ui.control.Button
        lampIcon                matlab.ui.control.Image
        TVIcon                  matlab.ui.control.Image
        heatingIcon             matlab.ui.control.Image
        TVSwitch                matlab.ui.control.Switch
        HeatingSwitch           matlab.ui.control.Switch
        LampSwitch              matlab.ui.control.Switch
        LightSwitch             matlab.ui.control.Switch
        tabTV                   matlab.ui.control.Image
        tabHeating              matlab.ui.control.Image
        tabLamp                 matlab.ui.control.Image
        lightIcon               matlab.ui.control.Image
        tabLight                matlab.ui.control.Image
        livingroom1             matlab.ui.control.Image
        livingroom2             matlab.ui.control.Image
        livingroom3             matlab.ui.control.Image
        livingroom4             matlab.ui.control.Image
        livingroomBackgr        matlab.ui.control.Image
        OvenIcon                matlab.ui.control.Image
        KitchenHeatingIcon      matlab.ui.control.Image
        KitchenLightIcon        matlab.ui.control.Image
        ChandelierIcon          matlab.ui.control.Image
        OvenSwitch              matlab.ui.control.Switch
        OvenLable               matlab.ui.control.Label
        KitchenHeatingSwitch    matlab.ui.control.Switch
        KitchenHeatingLable     matlab.ui.control.Label
        KitchenLightSwitch      matlab.ui.control.Switch
        KitchenLightLable       matlab.ui.control.Label
        ChandelierSwitch        matlab.ui.control.Switch
        ChandelierLabel         matlab.ui.control.Label
        return2                 matlab.ui.control.Button
        TabOven                 matlab.ui.control.Image
        TabKitchenHeating       matlab.ui.control.Image
        tabKitchenLight         matlab.ui.control.Image
        tabChandelier           matlab.ui.control.Image
        kitchen1                matlab.ui.control.Image
        kitchen2                matlab.ui.control.Image
        kitchen3                matlab.ui.control.Image
        kitchen4                matlab.ui.control.Image
        kitchenBackgr           matlab.ui.control.Image
        DropDownRadio           matlab.ui.control.DropDown
        return3                 matlab.ui.control.Button
        BedroomRadioLabel       matlab.ui.control.Label
        BedroomHeatingLabel     matlab.ui.control.Label
        BedroomLampLabel        matlab.ui.control.Label
        BedroomLightLabel       matlab.ui.control.Label
        BedroomRadioIcon        matlab.ui.control.Image
        BedroomHeatingIcon      matlab.ui.control.Image
        BedroomLampIcon         matlab.ui.control.Image
        BedroomLightIcon        matlab.ui.control.Image
        BedroomHeatingSwitch    matlab.ui.control.Switch
        BedroomLampSwitch       matlab.ui.control.Switch
        BedroomLightSwitch      matlab.ui.control.Switch
        TabRadio                matlab.ui.control.Image
        TabBedroomHeating       matlab.ui.control.Image
        TabBedroomLamp          matlab.ui.control.Image
        TabBedroomLight         matlab.ui.control.Image
        bedroom1                matlab.ui.control.Image
        bedroom2                matlab.ui.control.Image
        bedroom3                matlab.ui.control.Image
        bedroom4                matlab.ui.control.Image
        bedroomBackgr           matlab.ui.control.Image
        locked                  matlab.ui.control.Image
        unlocked                matlab.ui.control.Image
        return4                 matlab.ui.control.Button
        LockLable               matlab.ui.control.Label
        HallwayHeatingLable     matlab.ui.control.Label
        HallwayLightLabel       matlab.ui.control.Label
        LockIcon                matlab.ui.control.Image
        HallwayHeatingIcon      matlab.ui.control.Image
        HallwayLightIcon        matlab.ui.control.Image
        LockSwitch              matlab.ui.control.Switch
        HallwayHeatingSwitch    matlab.ui.control.Switch
        HallwayLightSwitch      matlab.ui.control.Switch
        TabLock                 matlab.ui.control.Image
        TabHallwayHeating       matlab.ui.control.Image
        TabHallwayLight         matlab.ui.control.Image
        hallway1                matlab.ui.control.Image
        hallway2                matlab.ui.control.Image
        hallwayBackgr           matlab.ui.control.Image
        return5                 matlab.ui.control.Button
        DryerLable              matlab.ui.control.Label
        WashingMachineLable     matlab.ui.control.Label
        BathroomHeatingLable    matlab.ui.control.Label
        MirrorLightLable        matlab.ui.control.Label
        BathroomLightLable      matlab.ui.control.Label
        DryerIcon               matlab.ui.control.Image
        WashingMachineIcon      matlab.ui.control.Image
        BathroomHeatingIcon     matlab.ui.control.Image
        MirrorLightIcon         matlab.ui.control.Image
        BathroomLightIcon       matlab.ui.control.Image
        DryerSwitch             matlab.ui.control.Switch
        WashingMachineSwitch    matlab.ui.control.Switch
        BathroomHeatingSwitch   matlab.ui.control.Switch
        MirrorLightSwitch       matlab.ui.control.Switch
        BathroomLightSwitch     matlab.ui.control.Switch
        TabDryer                matlab.ui.control.Image
        TabWashingMachine       matlab.ui.control.Image
        TabBathroomHeating      matlab.ui.control.Image
        TabMirrorLight          matlab.ui.control.Image
        TabBathroomLight        matlab.ui.control.Image
        bathroom1               matlab.ui.control.Image
        bathroom2               matlab.ui.control.Image
        bathroom3               matlab.ui.control.Image
        bathroom4               matlab.ui.control.Image
        bathroomBackgr          matlab.ui.control.Image
        TVLabel                 matlab.ui.control.Label
        HeatingLabel            matlab.ui.control.Label
        LampLabel               matlab.ui.control.Label
    end

    
    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);

            app.HomeBackgroud.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.StartButton.Visible='off';
            app.TourButton.Visible='off';
            app.tourPanel.Visible='off';
            app.roof.Visible='off';


            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end

        % Button pushed function: TourButton
        function TourButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            app.tourPanel.Visible='on';
            app.StartButton.Visible='off';
            app.TourButton.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeBackgroud.Visible='off';
            app.return2home.Visible='off';
            app.roof.Visible='off';

            global audioPlayer;
            app.TourButton.Visible='off';
            img=imread('background3.jpg');
            imshow(img,'Parent',app.imageAxes);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\tour.mov');
            video =VideoReader(fullFilePath);
            axes(app.tvchannel);
            [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\SwanLake.mp3');
            audioPlayer=audioplayer(audio,fs);
            play(audioPlayer);
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',app.tvchannel);
                pause(1/video.FrameRate);
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            global audioPlayer;
            stop(audioPlayer);
            app.tourPanel.Visible="off";
            app.StartButton.Visible='on';
            app.TourButton.Visible='on';
            app.HomeFlowNavigatorLabel.Visible='on';
            app.secretmessageeLabel.Visible='on';
            app.HomeBackgroud.Visible='on';
            app.roof.Visible='on';

            
        end

        % Button pushed function: return2home
        function return2homeButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';

            app.HomeBackgroud.Visible='on';
            app.secretmessageeLabel.Visible='on';
            app.HomeFlowNavigatorLabel.Visible='on';
            app.StartButton.Visible='on';
            app.TourButton.Visible='on';
            app.roof.Visible='on';
        end

        % Button pushed function: LivingroomButton
        function LivingroomButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';

            app.livingroomBackgr.Visible='on';
            app.livingroom1.Visible='on';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='on';
            app.LightSwitch.Visible='on';
            app.lightIcon.Visible='on';
            app.tabLamp.Visible='on';
            app.tabHeating.Visible='on';
            app.tabTV.Visible='on';
            app.LampSwitch.Visible='on';
            app.HeatingSwitch.Visible='on';
            app.TVSwitch.Visible='on';
            app.heatingIcon.Visible='on';
            app.TVIcon.Visible='on';
            app.lampIcon.Visible='on';
            app.return1.Visible='on';
            app.lightlabel.Visible='on';
            app.lamplabel.Visible='on';
            app.heatinglabel.Visible='on';
            app.tvlabel.Visible='on';

            if app.LightSwitch.Value=='1'
               if app.LampSwitch.Value=='1'
                    app.livingroom4.Visible='on';
                    app.livingroom1.Visible='off';
                    app.livingroom2.Visible='off';
                    app.livingroom3.Visible='off';
               else
                    app.livingroom2.Visible='on';
                    app.livingroom1.Visible='off';
                    app.livingroom4.Visible='off';
                    app.livingroom3.Visible='off';
               end
           else
            if app.LightSwitch.Value=='0' 
                if app.LampSwitch.Value=='0'
                 app.livingroom1.Visible='on';
                 app.livingroom2.Visible='off';
                 app.livingroom4.Visible='off';
                 app.livingroom3.Visible='off';
                else
                 app.livingroom3.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom2.Visible='off';
                 app.livingroom4.Visible='off';
                end
            end    
            
            end

            if app.LampSwitch.Value=='1' 
                 if app.LightSwitch.Value=='1'
                 app.livingroom4.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom2.Visible='off';
                 app.livingroom3.Visible='off';
                 else
                app.livingroom3.Visible='on';
                app.livingroom1.Visible='off';
                app.livingroom2.Visible='off';
                app.livingroom4.Visible='off';
                 end

           else
            if app.LampSwitch.Value=='0' 
                if app.LightSwitch.Value=='0'
                 app.livingroom1.Visible='on';
                 app.livingroom2.Visible='off';
                 app.livingroom3.Visible='off';
                 app.livingroom4.Visible='off';
                else
                 app.livingroom2.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom4.Visible='off';
                 app.livingroom3.Visible='off'; 
                end      
            end      
            
            end
        end

        % Value changed function: LightSwitch
        function LightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            if app.LightSwitch.Value=='1'
               if app.LampSwitch.Value=='1'
                    app.livingroom4.Visible='on';
                    app.livingroom1.Visible='off';
                    app.livingroom2.Visible='off';
                    app.livingroom3.Visible='off';
               else
                    app.livingroom2.Visible='on';
                    app.livingroom1.Visible='off';
                    app.livingroom4.Visible='off';
                    app.livingroom3.Visible='off';
               end
           else
            if app.LightSwitch.Value=='0' 
                if app.LampSwitch.Value=='0'
                 app.livingroom1.Visible='on';
                 app.livingroom2.Visible='off';
                 app.livingroom4.Visible='off';
                 app.livingroom3.Visible='off';
                else
                 app.livingroom3.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom2.Visible='off';
                 app.livingroom4.Visible='off';
                end   
            end    
            
            end
        end

        % Value changed function: LampSwitch
        function LampSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.LampSwitch.Value=='1' 
                 if app.LightSwitch.Value=='1'
                 app.livingroom4.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom2.Visible='off';
                 app.livingroom3.Visible='off';
                 else
                app.livingroom3.Visible='on';
                app.livingroom1.Visible='off';
                app.livingroom2.Visible='off';
                app.livingroom4.Visible='off';
                 end

           else
            if app.LampSwitch.Value=='0' 
                if app.LightSwitch.Value=='0'
                 app.livingroom1.Visible='on';
                 app.livingroom2.Visible='off';
                 app.livingroom3.Visible='off';
                 app.livingroom4.Visible='off';
                else
                 app.livingroom2.Visible='on';
                 app.livingroom1.Visible='off';
                 app.livingroom4.Visible='off';
                 app.livingroom3.Visible='off'; 
                end
            end      
            
            end
        end

        % Value changed function: HeatingSwitch
        function HeatingSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.HeatingSwitch.Value=='1'
               heatingFig=uifigure('Name','Heating','Position',[180,140,500,400]);
               uiimage(heatingFig,'ImageSource','thermostat.png','Position',[0,-50,500,490]);
               tempSpinner=uispinner(heatingFig,'Value',14.0,'Limits',[14,25],'Position',[300,20,150,40], ...
                 'ValueChangedFcn',@spinnerCallback,'FontSize',23,'HorizontalAlignment','center');
               tempLabel=uilabel(heatingFig,'Position',[65,35,300,400],'Text','Set temp to: ', ...
                  'HorizontalAlignment','center','FontSize',35);
               uibutton(heatingFig,'Text','SET','Position',[230,20,40,40],'ButtonPushedFcn',@setButtonCallback);
               heatTimer=timer('TimerFcn',@timerCallback,'StartDelay',15);
               [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\heater.wav');
               audioPlayer=audioplayer(audio,fs);
            end

            function spinnerCallback(~, event)
                tempLabel.Text=['Set temp to: ',num2str(tempSpinner.Value)];
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.mp3');
                sound(y,Fs);
            end
            function setButtonCallback(~, event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\pick.wav');
                sound(y,Fs);
                delete(heatingFig);
                start(heatTimer);
                play(audioPlayer);
            end
            function timerCallback(~, event)    
               alertFig = uifigure('Name', 'Heating Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text','Living room has heated!', 'FontSize', 14, ...
                   'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
        end

        % Value changed function: TVSwitch
        function TVSwitchValueChanged(app, event)
           [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            if app.TVSwitch.Value=='1'
            tvFig=uifigure('Name','TV','Position',[180,140,580,400]);
            tvAxes=uiaxes(tvFig,'Position',[-30,0,500,490]);
            tvImg=imread("tv.jpeg");
            imshow(tvImg,'Parent',tvAxes);
            uiimage(tvFig,'ImageSource','remote.png','Position',[430,-10,170,200]);
            videos=uiaxes(tvFig,'Position',[-15,133,500,250],'Visible','off');
            onButton=uibutton(tvFig,'Text','On','Position',[458,135,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@onButtonCallback);
            Button1=uibutton(tvFig,'Text','1','Position',[460,103,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button1Callback);
            Button2=uibutton(tvFig,'Text','2','Position',[500,103,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button2Callback);
            Button3=uibutton(tvFig,'Text','3','Position',[538,103,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button3Callback);
            Button4=uibutton(tvFig,'Text','4','Position',[460,70,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button4Callback);
            Button5=uibutton(tvFig,'Text','5','Position',[500,70,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button5Callback);
            Button6=uibutton(tvFig,'Text','6','Position',[538,70,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button6Callback);
            Button7=uibutton(tvFig,'Text','7','Position',[460,37,30,20],'HorizontalAlignment','center','ButtonPushedFcn',@button7Callback);
            backButton=uibutton(tvFig,'Text','Back','Position',[500,37,70,20],'HorizontalAlignment','center','ButtonPushedFcn',@backButtonCallback);
            end
            
            function onButtonCallback(~,event)
                global audioPlayer;
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\Avaz.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Avaz.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end
                function button1Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\dbr.mov');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\dbr.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
                end
           function button2Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\halal.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\halal.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
                end
            function button3Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\PezaMuslimaneReza.mov');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\PezaMuslimaneReza.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end  
            function button4Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\news.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\news.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end
            function button5Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\Tom Jerry.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Tom Jerry.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end
            function button6Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\telanovela.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\telanovela.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end
            function button7Callback(~,event)
                    global audioPlayer;
                    stop(audioPlayer);
            fullFilePath=fullfile('C:\Users\User\Desktop\matlab_project\video\Chipi Chipi Chapa Chapa.mp4');
            video =VideoReader(fullFilePath);
            axes(videos);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Chipi Chipi Chapa Chapa.mp3');
                 audioPlayer=audioplayer(audio,fs);
                 play(audioPlayer);
          
            while hasFrame(video)
                frame=readFrame(video);
                imshow(frame,'Parent',videos);
                pause(1/video.FrameRate);
            end
            end
            function backButtonCallback(~,event)
              global audioPlayer;
                    stop(audioPlayer);
                    delete(tvFig);
            end
            
        end

        % Button pushed function: return1
        function return1ButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.livingroomBackgr.Visible='off';
            app.livingroom1.Visible='off';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='off';
            app.LightSwitch.Visible='off';
            app.lightIcon.Visible='off';
            app.tabLamp.Visible='off';
            app.tabHeating.Visible='off';
            app.tabTV.Visible='off';
            app.LampSwitch.Visible='off';
            app.HeatingSwitch.Visible='off';
            app.TVSwitch.Visible='off';
            app.heatingIcon.Visible='off';
            app.TVIcon.Visible='off';
            app.lampIcon.Visible='off';
            app.return1.Visible='off';
            app.lightlabel.Visible='off';
            app.lamplabel.Visible='off';
            app.heatinglabel.Visible='off';
            app.tvlabel.Visible='off';
            app.StartButton.Visible='off';
            app.TourButton.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeBackgroud.Visible='off';
            app.roof.Visible='off';

            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end

        % Button pushed function: KitchenButton
        function KitchenButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';
            app.livingroomBackgr.Visible='off';
            app.livingroom1.Visible='off';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='off';
            app.LightSwitch.Visible='off';
            app.lightIcon.Visible='off';
            app.tabLamp.Visible='off';
            app.tabHeating.Visible='off';
            app.tabTV.Visible='off';
            app.LampSwitch.Visible='off';
            app.HeatingSwitch.Visible='off';
            app.TVSwitch.Visible='off';
            app.heatingIcon.Visible='off';
            app.TVIcon.Visible='off';
            app.lampIcon.Visible='off';
            app.return1.Visible='off';
            app.lightlabel.Visible='off';
            app.lamplabel.Visible='off';
            app.heatinglabel.Visible='off';
            app.tvlabel.Visible='off';

            app.kitchenBackgr.Visible='on';
            app.kitchen1.Visible='on';
            app.kitchen2.Visible='on';
            app.kitchen3.Visible='on';
            app.kitchen4.Visible='on';
            app.tabChandelier.Visible='on';
            app.tabKitchenLight.Visible='on';
            app.TabKitchenHeating.Visible='on';
            app.TabOven.Visible='on';
            app.return2.Visible='on';
            app.ChandelierLabel.Visible='on';
            app.ChandelierSwitch.Visible='on';
            app.KitchenLightLable.Visible='on';
            app.KitchenLightSwitch.Visible='on';
            app.KitchenHeatingLable.Visible='on';
            app.KitchenHeatingSwitch.Visible='on';
            app.OvenLable.Visible='on';
            app.OvenSwitch.Visible='on';
            app.ChandelierIcon.Visible='on';
            app.KitchenLightIcon.Visible='on';
            app.KitchenHeatingIcon.Visible='on';
            app.OvenIcon.Visible='on';

            if app.ChandelierSwitch.Value=='1'
               if app.KitchenLightSwitch.Value=='1'
                    app.kitchen4.Visible='on';
                    app.kitchen1.Visible='off';
                    app.kitchen2.Visible='off';
                    app.kitchen3.Visible='off';
               else
                    app.kitchen2.Visible='on';
                    app.kitchen1.Visible='off';
                    app.kitchen4.Visible='off';
                    app.kitchen3.Visible='off';
               end
           else
            if app.ChandelierSwitch.Value=='0' 
                if app.KitchenLightSwitch.Value=='0'
                 app.kitchen1.Visible='on';
                 app.kitchen2.Visible='off';
                 app.kitchen4.Visible='off';
                 app.kitchen3.Visible='off';
                else
                 app.kitchen3.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen2.Visible='off';
                 app.kitchen4.Visible='off';
                end    
            end    
            
            end

            if app.KitchenLightSwitch.Value=='1' 
                 if app.ChandelierSwitch.Value=='1'
                 app.kitchen4.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen2.Visible='off';
                 app.kitchen3.Visible='off';
                 else
                app.kitchen3.Visible='on';
                app.kitchen1.Visible='off';
                app.kitchen2.Visible='off';
                app.kitchen4.Visible='off';
                 end

           else
            if app.KitchenLightSwitch.Value=='0' 
                if app.ChandelierSwitch.Value=='0'
                 app.kitchen1.Visible='on';
                 app.kitchen2.Visible='off';
                 app.kitchen3.Visible='off';
                 app.kitchen4.Visible='off';
                else
                 app.kitchen2.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen4.Visible='off';
                 app.kitchen3.Visible='off'; 
                end
            end      
            
            end
        end

        % Value changed function: ChandelierSwitch
        function ChandelierSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.ChandelierSwitch.Value=='1'
               if app.KitchenLightSwitch.Value=='1'
                    app.kitchen4.Visible='on';
                    app.kitchen1.Visible='off';
                    app.kitchen2.Visible='off';
                    app.kitchen3.Visible='off';
               else
                    app.kitchen2.Visible='on';
                    app.kitchen1.Visible='off';
                    app.kitchen4.Visible='off';
                    app.kitchen3.Visible='off';
               end
           else
            if app.ChandelierSwitch.Value=='0' 
                if app.KitchenLightSwitch.Value=='0'
                 app.kitchen1.Visible='on';
                 app.kitchen2.Visible='off';
                 app.kitchen4.Visible='off';
                 app.kitchen3.Visible='off';
                else
                 app.kitchen3.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen2.Visible='off';
                 app.kitchen4.Visible='off';
                end      
            end    
            
            end
        end

        % Value changed function: KitchenLightSwitch
        function KitchenLightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.KitchenLightSwitch.Value=='1' 
                 if app.ChandelierSwitch.Value=='1'
                 app.kitchen4.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen2.Visible='off';
                 app.kitchen3.Visible='off';
                 else
                app.kitchen3.Visible='on';
                app.kitchen1.Visible='off';
                app.kitchen2.Visible='off';
                app.kitchen4.Visible='off';
                 end

           else
            if app.KitchenLightSwitch.Value=='0' 
                if app.ChandelierSwitch.Value=='0'
                 app.kitchen1.Visible='on';
                 app.kitchen2.Visible='off';
                 app.kitchen3.Visible='off';
                 app.kitchen4.Visible='off';
                else
                 app.kitchen2.Visible='on';
                 app.kitchen1.Visible='off';
                 app.kitchen4.Visible='off';
                 app.kitchen3.Visible='off'; 
                end  
            end      
            
            end
        end

        % Value changed function: KitchenHeatingSwitch
        function KitchenHeatingSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.KitchenHeatingSwitch.Value=='1'
                heatingFig=uifigure('Name','Heating','Position',[180,140,500,400]);
                uiimage(heatingFig,'ImageSource','thermostat.png','Position',[0,-50,500,490]);
                tempSpinner=uispinner(heatingFig,'Value',14.0,'Limits',[14,25],'Position',[300,20,150,40],'ValueChangedFcn',@spinnerCallback, ...
                    'FontSize',23,'HorizontalAlignment','center');
                tempLabel=uilabel(heatingFig,'Position',[65,35,300,400],'Text','Set temp to: ','HorizontalAlignment','center','FontSize',35);
                uibutton(heatingFig,'Text','SET','Position',[230,20,40,40],'ButtonPushedFcn',@setButtonCallback);
                heatTimer=timer('TimerFcn',@timerCallback,'StartDelay',15);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\heater.wav');
                 audioPlayer=audioplayer(audio,fs);
            end

            function spinnerCallback(~, event)
                tempLabel.Text=['Set temp to: ',num2str(tempSpinner.Value)];
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.mp3');
                sound(y,Fs);
            end
            function setButtonCallback(~, event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\pick.wav');
                sound(y,Fs);
                delete(heatingFig);
                start(heatTimer);
                play(audioPlayer);
            end
            function timerCallback(~, event)    
               alertFig = uifigure('Name', 'Heating Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text','Kitchen has heated!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
            
        end

        % Value changed function: OvenSwitch
        function OvenSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.OvenSwitch.Value=='1'   
                OvenFig = uifigure('Name', 'Oven', 'Position', [180, 140, 500, 400]);
                uiimage(OvenFig, 'ImageSource', 'oven.png', 'Position', [-49, -100, 600, 500] );
                uispinner(OvenFig, 'Value', 200, 'Limits', [150 300], 'Position', [220, 333, 55, 27], 'BackgroundColor', 'k', ...
                    'FontColor', 'w', 'ValueChangingFcn', @(src, event) spinnerValueChanging(app, src, event));
                uibutton(OvenFig, 'BackgroundColor', 'k', 'FontColor', 'w', 'Position', [210, 305, 70, 27], 'Text', 'Start', 'ButtonPushedFcn', @setButtonCallback);  
                ovenTimer=timer('TimerFcn',@timerCallback,'StartDelay',9);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\oven.wav');
                 audioPlayer=audioplayer(audio,fs);
            end

            function spinnerValueChanging(app, scr, event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\beep sound.wav');
                sound(y,Fs);
            end
            
            
            function setButtonCallback(~, event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\beep sound.wav');
                sound(y,Fs);
                start(ovenTimer);
                delete(OvenFig);
                play(audioPlayer)
            end
            
            function timerCallback(~,event)
               alertFig = uifigure('Name', 'Oven Alert', 'Position', [300, 300, 200, 100]);
                uilabel(alertFig, 'Text', 'The oven has preheated!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
        end

        % Button pushed function: return2
        function return2ButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.kitchenBackgr.Visible='off';
            app.kitchen1.Visible='off';
            app.kitchen2.Visible='off';
            app.kitchen3.Visible='off';
            app.kitchen4.Visible='off';
            app.tabChandelier.Visible='off';
            app.tabKitchenLight.Visible='off';
            app.TabKitchenHeating.Visible='off';
            app.TabOven.Visible='off';
            app.return2.Visible='off';
            app.ChandelierLabel.Visible='off';
            app.ChandelierSwitch.Visible='off';
            app.KitchenLightLable.Visible='off';
            app.KitchenLightSwitch.Visible='off';
            app.KitchenHeatingLable.Visible='off';
            app.KitchenHeatingSwitch.Visible='off';
            app.OvenLable.Visible='off';
            app.OvenSwitch.Visible='off';
            app.ChandelierIcon.Visible='off';
            app.KitchenLightIcon.Visible='off';
            app.KitchenHeatingIcon.Visible='off';
            app.OvenIcon.Visible='off';

            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end

        % Button pushed function: Bedroombutton
        function BedroombuttonButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';
            app.kitchenBackgr.Visible='off';
            app.kitchen1.Visible='off';
            app.kitchen2.Visible='off';
            app.kitchen3.Visible='off';
            app.kitchen4.Visible='off';
            app.tabChandelier.Visible='off';
            app.tabKitchenLight.Visible='off';
            app.TabKitchenHeating.Visible='off';
            app.TabOven.Visible='off';
            app.return2.Visible='off';
            app.ChandelierLabel.Visible='off';
            app.ChandelierSwitch.Visible='off';
            app.KitchenLightLable.Visible='off';
            app.KitchenLightSwitch.Visible='off';
            app.KitchenHeatingLable.Visible='off';
            app.KitchenHeatingSwitch.Visible='off';
            app.OvenLable.Visible='off';
            app.OvenSwitch.Visible='off';
            app.ChandelierIcon.Visible='off';
            app.KitchenLightIcon.Visible='off';
            app.KitchenHeatingIcon.Visible='off';
            app.OvenIcon.Visible='off';
            app.livingroomBackgr.Visible='off';
            app.livingroom1.Visible='off';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='off';
            app.LightSwitch.Visible='off';
            app.lightIcon.Visible='off';
            app.tabLamp.Visible='off';
            app.tabHeating.Visible='off';
            app.tabTV.Visible='off';
            app.LampSwitch.Visible='off';
            app.HeatingSwitch.Visible='off';
            app.TVSwitch.Visible='off';
            app.heatingIcon.Visible='off';
            app.TVIcon.Visible='off';
            app.lampIcon.Visible='off';
            app.return1.Visible='off';
            app.lightlabel.Visible='off';
            app.lamplabel.Visible='off';
            app.heatinglabel.Visible='off';
            app.tvlabel.Visible='off';

            app.bedroomBackgr.Visible='on';
            app.bedroom4.Visible='on';
            app.bedroom3.Visible='on';
            app.bedroom2.Visible='on';
            app.bedroom1.Visible='on';
            app.TabBedroomLight.Visible='on';
            app.TabBedroomLamp.Visible='on';
            app.TabBedroomHeating.Visible='on';
            app.TabRadio.Visible='on';
            app.BedroomLightSwitch.Visible='on';
            app.BedroomLampSwitch.Visible='on';
            app.BedroomHeatingSwitch.Visible='on';
            app.DropDownRadio.Visible='on';
            app.BedroomLightIcon.Visible='on';
            app.BedroomLampIcon.Visible='on';
            app.BedroomHeatingIcon.Visible='on';
            app.BedroomRadioIcon.Visible='on';
            app.BedroomLightLabel.Visible='on';
            app.BedroomLampLabel.Visible='on';
            app.BedroomHeatingLabel.Visible='on';
            app.BedroomRadioLabel.Visible='on';
            app.return3.Visible='on';

            if app.BedroomLightSwitch.Value=='1'
               if app.BedroomLampSwitch.Value=='1'
                    app.bedroom4.Visible='on';
                    app.bedroom1.Visible='off';
                    app.bedroom2.Visible='off';
                    app.bedroom3.Visible='off';
               else
                    app.bedroom2.Visible='on';
                    app.bedroom1.Visible='off';
                    app.bedroom4.Visible='off';
                    app.bedroom3.Visible='off';
               end
           else
            if app.BedroomLightSwitch.Value=='0' 
                if app.BedroomLampSwitch.Value=='0'
                 app.bedroom1.Visible='on';
                 app.bedroom2.Visible='off';
                 app.bedroom4.Visible='off';
                 app.bedroom3.Visible='off';
                else
                 app.bedroom3.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom2.Visible='off';
                 app.bedroom4.Visible='off';
                end     
            end    
            
            end

            if app.BedroomLampSwitch.Value=='1' 
                 if app.BedroomLightSwitch.Value=='1'
                 app.bedroom4.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom2.Visible='off';
                 app.bedroom3.Visible='off';
                 else
                app.bedroom3.Visible='on';
                app.bedroom1.Visible='off';
                app.bedroom2.Visible='off';
                app.bedroom4.Visible='off';
                 end

           else
            if app.BedroomLampSwitch.Value=='0' 
                if app.BedroomLightSwitch.Value=='0'
                 app.bedroom1.Visible='on';
                 app.bedroom2.Visible='off';
                 app.bedroom3.Visible='off';
                 app.bedroom4.Visible='off';
                else
                 app.bedroom2.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom4.Visible='off';
                 app.bedroom3.Visible='off'; 
                end  
            end      
            
            end
        end

        % Value changed function: BedroomLightSwitch
        function BedroomLightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.BedroomLightSwitch.Value=='1'
               if app.BedroomLampSwitch.Value=='1'
                    app.bedroom4.Visible='on';
                    app.bedroom1.Visible='off';
                    app.bedroom2.Visible='off';
                    app.bedroom3.Visible='off';
               else
                    app.bedroom2.Visible='on';
                    app.bedroom1.Visible='off';
                    app.bedroom4.Visible='off';
                    app.bedroom3.Visible='off';
               end
           else
            if app.BedroomLightSwitch.Value=='0' 
                if app.BedroomLampSwitch.Value=='0'
                 app.bedroom1.Visible='on';
                 app.bedroom2.Visible='off';
                 app.bedroom4.Visible='off';
                 app.bedroom3.Visible='off';
                else
                 app.bedroom3.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom2.Visible='off';
                 app.bedroom4.Visible='off';
                end  
            end    
            
            end
            
        end

        % Value changed function: BedroomLampSwitch
        function BedroomLampSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.BedroomLampSwitch.Value=='1' 
                 if app.BedroomLightSwitch.Value=='1'
                 app.bedroom4.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom2.Visible='off';
                 app.bedroom3.Visible='off';
                 else
                app.bedroom3.Visible='on';
                app.bedroom1.Visible='off';
                app.bedroom2.Visible='off';
                app.bedroom4.Visible='off';
                 end

           else
            if app.BedroomLampSwitch.Value=='0' 
                if app.BedroomLightSwitch.Value=='0'
                 app.bedroom1.Visible='on';
                 app.bedroom2.Visible='off';
                 app.bedroom3.Visible='off';
                 app.bedroom4.Visible='off';
                else
                 app.bedroom2.Visible='on';
                 app.bedroom1.Visible='off';
                 app.bedroom4.Visible='off';
                 app.bedroom3.Visible='off'; 
                end
            end      
            
            end
            
        end

        % Value changed function: BedroomHeatingSwitch
        function BedroomHeatingSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.BedroomHeatingSwitch.Value=='1'
                heatingFig=uifigure('Name','Heating','Position',[180,140,500,400]);
                uiimage(heatingFig,'ImageSource','thermostat.png','Position',[0,-50,500,490]);
                tempSpinner=uispinner(heatingFig,'Value',14.0,'Limits',[14,25],'Position',[300,20,150,40],'ValueChangedFcn',@spinnerCallback, ...
                    'FontSize',23,'HorizontalAlignment','center');
                tempLabel=uilabel(heatingFig,'Position',[65,35,300,400],'Text','Set temp to: ','HorizontalAlignment','center','FontSize',35);
                uibutton(heatingFig,'Text','SET','Position',[230,20,40,40],'ButtonPushedFcn',@setButtonCallback);
                heatTimer=timer('TimerFcn',@timerCallback,'StartDelay',15);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\heater.wav');
                 audioPlayer=audioplayer(audio,fs);
            end

            function spinnerCallback(~,event)
                tempLabel.Text=['Set temp to: ',num2str(tempSpinner.Value)];
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.mp3');
                sound(y,Fs);
            end
            function setButtonCallback(~,event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\pick.wav');
                sound(y,Fs);
                delete(heatingFig);
                start(heatTimer);
                play(audioPlayer);
            end
            function timerCallback(~,event)    
               alertFig = uifigure('Name', 'Heating Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text','Bedroom has heated!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
            
        end

        % Value changed function: DropDownRadio
        function DropDownRadioValueChanged(app, event)
            value = app.DropDownRadio.Value;
            clear sound;
            switch value
            case 'AdDuha'
            [y,Fs]=audioread('C:/Users/User/Desktop/matlab_project/audio/AdDuha.wav');
            sound(y,Fs);
            return;
         
            case 'MaherZain'
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\MaherZain.wav');
             sound(y,Fs);
             return;
             case 'Falestini'
             [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Falestini.wav');
             sound(y,Fs);
             return;
             
             case 'Off'
                 return;
            end
            
        end

        % Button pushed function: return3
        function return3ButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.bedroomBackgr.Visible='off';
            app.bedroom4.Visible='off';
            app.bedroom3.Visible='off';
            app.bedroom2.Visible='off';
            app.bedroom1.Visible='off';
            app.TabBedroomLight.Visible='off';
            app.TabBedroomLamp.Visible='off';
            app.TabBedroomHeating.Visible='off';
            app.TabRadio.Visible='off';
            app.BedroomLightSwitch.Visible='off';
            app.BedroomLampSwitch.Visible='off';
            app.BedroomHeatingSwitch.Visible='off';
            app.DropDownRadio.Visible='off';
            app.BedroomLightIcon.Visible='off';
            app.BedroomLampIcon.Visible='off';
            app.BedroomHeatingIcon.Visible='off';
            app.BedroomRadioIcon.Visible='off';
            app.BedroomLightLabel.Visible='off';
            app.BedroomLampLabel.Visible='off';
            app.BedroomHeatingLabel.Visible='off';
            app.BedroomRadioLabel.Visible='off';
            app.return3.Visible='off';
            app.StartButton.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeBackgroud.Visible='off';
            app.TourButton.Visible='off';
            app.roof.Visible='off';

            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end

        % Button pushed function: HallwayButton
        function HallwayButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';
            app.livingroomBackgr.Visible='off';
            app.livingroom1.Visible='off';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='off';
            app.LightSwitch.Visible='off';
            app.lightIcon.Visible='off';
            app.tabLamp.Visible='off';
            app.tabHeating.Visible='off';
            app.tabTV.Visible='off';
            app.LampSwitch.Visible='off';
            app.HeatingSwitch.Visible='off';
            app.TVSwitch.Visible='off';
            app.heatingIcon.Visible='off';
            app.TVIcon.Visible='off';
            app.lampIcon.Visible='off';
            app.return1.Visible='off';
            app.lightlabel.Visible='off';
            app.lamplabel.Visible='off';
            app.heatinglabel.Visible='off';
            app.tvlabel.Visible='off';
            app.kitchenBackgr.Visible='off';
            app.kitchen1.Visible='off';
            app.kitchen2.Visible='off';
            app.kitchen3.Visible='off';
            app.kitchen4.Visible='off';
            app.tabChandelier.Visible='off';
            app.tabKitchenLight.Visible='off';
            app.TabKitchenHeating.Visible='off';
            app.TabOven.Visible='off';
            app.return2.Visible='off';
            app.ChandelierLabel.Visible='off';
            app.ChandelierSwitch.Visible='off';
            app.KitchenLightLable.Visible='off';
            app.KitchenLightSwitch.Visible='off';
            app.KitchenHeatingLable.Visible='off';
            app.KitchenHeatingSwitch.Visible='off';
            app.OvenLable.Visible='off';
            app.OvenSwitch.Visible='off';
            app.ChandelierIcon.Visible='off';
            app.KitchenLightIcon.Visible='off';
            app.KitchenHeatingIcon.Visible='off';
            app.OvenIcon.Visible='off';
            app.bedroomBackgr.Visible='off';
            app.bedroom4.Visible='off';
            app.bedroom3.Visible='off';
            app.bedroom2.Visible='off';
            app.bedroom1.Visible='off';
            app.TabBedroomLight.Visible='off';
            app.TabBedroomLamp.Visible='off';
            app.TabBedroomHeating.Visible='off';
            app.TabRadio.Visible='off';
            app.BedroomLightSwitch.Visible='off';
            app.BedroomLampSwitch.Visible='off';
            app.BedroomHeatingSwitch.Visible='off';
            app.DropDownRadio.Visible='off';
            app.BedroomLightIcon.Visible='off';
            app.BedroomLampIcon.Visible='off';
            app.BedroomHeatingIcon.Visible='off';
            app.BedroomRadioIcon.Visible='off';
            app.BedroomLightLabel.Visible='off';
            app.BedroomLampLabel.Visible='off';
            app.BedroomHeatingLabel.Visible='off';
            app.BedroomRadioLabel.Visible='off';
            app.return3.Visible='off';
            app.locked.Visible='off';

            app.hallwayBackgr.Visible='on';
            app.hallway1.Visible='on';
            app.hallway2.Visible='on';
            app.TabHallwayLight.Visible='on';
            app.TabHallwayHeating.Visible='on';
            app.TabLock.Visible='on';
            app.HallwayLightSwitch.Visible='on';
            app.HallwayHeatingSwitch.Visible='on';
            app.LockSwitch.Visible='on';
            app.HallwayLightIcon.Visible='on';
            app.HallwayHeatingIcon.Visible='on';
            app.LockIcon.Visible='on';
            app.HallwayLightLabel.Visible='on';
            app.HallwayHeatingLable.Visible='on';
            app.LockLable.Visible='on';
            app.return4.Visible='on';
            app.unlocked.Visible='on';

             if app.HallwayLightSwitch.Value=='1'
                app.hallway1.Visible='off';
                app.hallway2.Visible='on';
            elseif app.HallwayLightSwitch.Value=='0'
                app.hallway2.Visible='off';
                app.hallway1.Visible='on';
             end

             if app.LockSwitch.Value=='0'
                app.unlocked.Visible='on';
                app.locked.Visible='off';
            else
                app.unlocked.Visible='off';
                app.locked.Visible='on';
            end
        end

        % Value changed function: HallwayLightSwitch
        function HallwayLightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.HallwayLightSwitch.Value=='1'
                app.hallway1.Visible='off';
                app.hallway2.Visible='on';
            elseif app.HallwayLightSwitch.Value=='0'
                app.hallway2.Visible='off';
                app.hallway1.Visible='on';
            end
            
        end

        % Value changed function: HallwayHeatingSwitch
        function HallwayHeatingSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.HallwayHeatingSwitch.Value=='1'
                heatingFig=uifigure('Name','Heating','Position',[180,140,500,400]);
                uiimage(heatingFig,'ImageSource','thermostat.png','Position',[0,-50,500,490]);
                tempSpinner=uispinner(heatingFig,'Value',14.0,'Limits',[14,25],'Position',[300,20,150,40],'ValueChangedFcn',@spinnerCallback, ...
                    'FontSize',23,'HorizontalAlignment','center');
                tempLabel=uilabel(heatingFig,'Position',[65,35,300,400],'Text','Set temp to: ','HorizontalAlignment','center','FontSize',35);
                uibutton(heatingFig,'Text','SET','Position',[230,20,40,40],'ButtonPushedFcn',@setButtonCallback);
                heatTimer=timer('TimerFcn',@timerCallback,'StartDelay',15);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\heater.wav');
                 audioPlayer=audioplayer(audio,fs);
            end

            function spinnerCallback(~,event)
                tempLabel.Text=['Set temp to: ',num2str(tempSpinner.Value)];
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.mp3');
                sound(y,Fs);
            end
            function setButtonCallback(~,event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\pick.wav');
                sound(y,Fs);
                delete(heatingFig);
                start(heatTimer);
                play(audioPlayer);
            end
            function timerCallback(~,event)    
               alertFig = uifigure('Name', 'Heating Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text','Hallway has heated!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
            
        end

        % Value changed function: LockSwitch
        function LockSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Door Lock Sound Effect.mp3');
            sound(y,Fs);

            if app.LockSwitch.Value=='0'
                app.unlocked.Visible='on';
                app.locked.Visible='off';
            else
                app.unlocked.Visible='off';
                app.locked.Visible='on';
            end
        end

        % Button pushed function: return4
        function return4ButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.hallwayBackgr.Visible='off';
            app.hallway1.Visible='off';
            app.hallway2.Visible='off';
            app.TabHallwayLight.Visible='off';
            app.TabHallwayHeating.Visible='off';
            app.TabLock.Visible='off';
            app.HallwayLightSwitch.Visible='off';
            app.HallwayHeatingSwitch.Visible='off';
            app.LockSwitch.Visible='off';
            app.HallwayLightIcon.Visible='off';
            app.HallwayHeatingIcon.Visible='off';
            app.LockIcon.Visible='off';
            app.HallwayLightLabel.Visible='off';
            app.HallwayHeatingLable.Visible='off';
            app.LockLable.Visible='off';
            app.return4.Visible='off';
            app.StartButton.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeBackgroud.Visible='off';
            app.unlocked.Visible='off';
            app.locked.Visible='off';
            app.roof.Visible='off';

            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end

        % Button pushed function: BathroomButton
        function BathroomButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.background2.Visible='off';
            app.house_layout.Visible='off';
            app.PickaroomyouwishtonavigateLabel.Visible='off';
            app.Bedroombutton.Visible='off';
            app.LivingroomButton.Visible='off';
            app.BathroomButton.Visible='off';
            app.KitchenButton.Visible='off';
            app.HallwayButton.Visible='off';
            app.return2home.Visible='off';
            app.livingroomBackgr.Visible='off';
            app.livingroom1.Visible='off';
            app.livingroom2.Visible='off';
            app.livingroom3.Visible='off';
            app.livingroom4.Visible='off';
            app.tabLight.Visible='off';
            app.LightSwitch.Visible='off';
            app.lightIcon.Visible='off';
            app.tabLamp.Visible='off';
            app.tabHeating.Visible='off';
            app.tabTV.Visible='off';
            app.LampSwitch.Visible='off';
            app.HeatingSwitch.Visible='off';
            app.TVSwitch.Visible='off';
            app.heatingIcon.Visible='off';
            app.TVIcon.Visible='off';
            app.lampIcon.Visible='off';
            app.return1.Visible='off';
            app.lightlabel.Visible='off';
            app.lamplabel.Visible='off';
            app.heatinglabel.Visible='off';
            app.tvlabel.Visible='off';
            app.kitchenBackgr.Visible='off';
            app.kitchen1.Visible='off';
            app.kitchen2.Visible='off';
            app.kitchen3.Visible='off';
            app.kitchen4.Visible='off';
            app.tabChandelier.Visible='off';
            app.tabKitchenLight.Visible='off';
            app.TabKitchenHeating.Visible='off';
            app.TabOven.Visible='off';
            app.return2.Visible='off';
            app.ChandelierLabel.Visible='off';
            app.ChandelierSwitch.Visible='off';
            app.KitchenLightLable.Visible='off';
            app.KitchenLightSwitch.Visible='off';
            app.KitchenHeatingLable.Visible='off';
            app.KitchenHeatingSwitch.Visible='off';
            app.OvenLable.Visible='off';
            app.OvenSwitch.Visible='off';
            app.ChandelierIcon.Visible='off';
            app.KitchenLightIcon.Visible='off';
            app.KitchenHeatingIcon.Visible='off';
            app.OvenIcon.Visible='off';
            app.bedroomBackgr.Visible='off';
            app.bedroom4.Visible='off';
            app.bedroom3.Visible='off';
            app.bedroom2.Visible='off';
            app.bedroom1.Visible='off';
            app.TabBedroomLight.Visible='off';
            app.TabBedroomLamp.Visible='off';
            app.TabBedroomHeating.Visible='off';
            app.TabRadio.Visible='off';
            app.BedroomLightSwitch.Visible='off';
            app.BedroomLampSwitch.Visible='off';
            app.BedroomHeatingSwitch.Visible='off';
            app.DropDownRadio.Visible='off';
            app.BedroomLightIcon.Visible='off';
            app.BedroomLampIcon.Visible='off';
            app.BedroomHeatingIcon.Visible='off';
            app.BedroomRadioIcon.Visible='off';
            app.BedroomLightLabel.Visible='off';
            app.BedroomLampLabel.Visible='off';
            app.BedroomHeatingLabel.Visible='off';
            app.BedroomRadioLabel.Visible='off';
            app.return3.Visible='off';
            app.hallwayBackgr.Visible='off';
            app.hallway1.Visible='off';
            app.hallway2.Visible='off';
            app.TabHallwayLight.Visible='off';
            app.TabHallwayHeating.Visible='off';
            app.TabLock.Visible='off';
            app.HallwayLightSwitch.Visible='off';
            app.HallwayHeatingSwitch.Visible='off';
            app.LockSwitch.Visible='off';
            app.HallwayLightIcon.Visible='off';
            app.HallwayHeatingIcon.Visible='off';
            app.LockIcon.Visible='off';
            app.HallwayLightLabel.Visible='off';
            app.HallwayHeatingLable.Visible='off';
            app.LockLable.Visible='off';
            app.return4.Visible='off';
            app.locked.Visible='off';
            app.unlocked.Visible='off';

            app.bathroomBackgr.Visible='on';
            app.bathroom4.Visible='on';
            app.bathroom3.Visible='on';
            app.bathroom2.Visible='on';
            app.bathroom1.Visible='on';
            app.TabBathroomLight.Visible='on';
            app.TabMirrorLight.Visible='on';
            app.TabBathroomHeating.Visible='on';
            app.TabWashingMachine.Visible='on';
            app.TabDryer.Visible='on';
            app.BathroomLightSwitch.Visible='on';
            app.MirrorLightSwitch.Visible='on';
            app.BathroomHeatingSwitch.Visible='on';
            app.WashingMachineSwitch.Visible='on';
            app.DryerSwitch.Visible='on';
            app.BathroomLightIcon.Visible='on';
            app.BathroomHeatingIcon.Visible='on';
            app.MirrorLightIcon.Visible='on';
            app.WashingMachineIcon.Visible='on';
            app.DryerIcon.Visible='on';
            app.BathroomLightLable.Visible='on';
            app.BathroomHeatingLable.Visible='on';
            app.MirrorLightLable.Visible='on';
            app.WashingMachineLable.Visible='on';
            app.DryerLable.Visible='on';
            app.return5.Visible='on';

            if app.BathroomLightSwitch.Value=='1'
               if app.MirrorLightSwitch.Value=='1'
                    app.bathroom4.Visible='on';
                    app.bathroom1.Visible='off';
                    app.bathroom2.Visible='off';
                    app.bathroom3.Visible='off';
               else
                    app.bathroom2.Visible='on';
                    app.bathroom1.Visible='off';
                    app.bathroom4.Visible='off';
                    app.bathroom3.Visible='off';
               end
           else
            if app.BathroomLightSwitch.Value=='0' 
                if app.MirrorLightSwitch.Value=='0'
                 app.bathroom1.Visible='on';
                 app.bathroom2.Visible='off';
                 app.bathroom4.Visible='off';
                 app.bathroom3.Visible='off';
                else
                 app.bathroom3.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom2.Visible='off';
                 app.bathroom4.Visible='off';
                end 
            end    
            
            end

            if app.MirrorLightSwitch.Value=='1' 
                 if app.BathroomLightSwitch.Value=='1'
                 app.bathroom4.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom2.Visible='off';
                 app.bathroom3.Visible='off';
                 else
                app.bathroom3.Visible='on';
                app.bathroom1.Visible='off';
                app.bathroom2.Visible='off';
                app.bathroom4.Visible='off';
                 end

           else
            if app.MirrorLightSwitch.Value=='0' 
                if app.BathroomLightSwitch.Value=='0'
                 app.bathroom1.Visible='on';
                 app.bathroom2.Visible='off';
                 app.bathroom3.Visible='off';
                 app.bathroom4.Visible='off';
                else
                 app.bathroom2.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom4.Visible='off';
                 app.bathroom3.Visible='off'; 
                end    
            end      
            
            end
        end

        % Value changed function: BathroomLightSwitch
        function BathroomLightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
             
            
            if app.BathroomLightSwitch.Value=='1'
               if app.MirrorLightSwitch.Value=='1'
                    app.bathroom4.Visible='on';
                    app.bathroom1.Visible='off';
                    app.bathroom2.Visible='off';
                    app.bathroom3.Visible='off';
               else
                    app.bathroom2.Visible='on';
                    app.bathroom1.Visible='off';
                    app.bathroom4.Visible='off';
                    app.bathroom3.Visible='off';
               end
           else
            if app.BathroomLightSwitch.Value=='0' 
                if app.MirrorLightSwitch.Value=='0'
                 app.bathroom1.Visible='on';
                 app.bathroom2.Visible='off';
                 app.bathroom4.Visible='off';
                 app.bathroom3.Visible='off';
                else
                 app.bathroom3.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom2.Visible='off';
                 app.bathroom4.Visible='off';
                end   
            end    
            
            end
               
        end

        % Value changed function: MirrorLightSwitch
        function MirrorLightSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            
            if app.MirrorLightSwitch.Value=='1' 
                 if app.BathroomLightSwitch.Value=='1'
                 app.bathroom4.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom2.Visible='off';
                 app.bathroom3.Visible='off';
                 else
                app.bathroom3.Visible='on';
                app.bathroom1.Visible='off';
                app.bathroom2.Visible='off';
                app.bathroom4.Visible='off';
                 end

           else
            if app.MirrorLightSwitch.Value=='0' 
                if app.BathroomLightSwitch.Value=='0'
                 app.bathroom1.Visible='on';
                 app.bathroom2.Visible='off';
                 app.bathroom3.Visible='off';
                 app.bathroom4.Visible='off';
                else
                 app.bathroom2.Visible='on';
                 app.bathroom1.Visible='off';
                 app.bathroom4.Visible='off';
                 app.bathroom3.Visible='off'; 
                end
            end      
            
            end
            
        end

        % Value changed function: BathroomHeatingSwitch
        function BathroomHeatingSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            if app.BathroomHeatingSwitch.Value=='1'
                heatingFig=uifigure('Name','Heating','Position',[180,140,500,400]);
                uiimage(heatingFig,'ImageSource','thermostat.png','Position',[0,-50,500,490]);
                tempSpinner=uispinner(heatingFig,'Value',14.0,'Limits',[14,25],'Position',[300,20,150,40],'ValueChangedFcn',@spinnerCallback, ...
                    'FontSize',23,'HorizontalAlignment','center');
                tempLabel=uilabel(heatingFig,'Position',[65,35,300,400],'Text','Set temp to: ','HorizontalAlignment','center','FontSize',35);
                uibutton(heatingFig,'Text','SET','Position',[230,20,40,40],'ButtonPushedFcn',@setButtonCallback);
                heatTimer=timer('TimerFcn',@timerCallback,'StartDelay',15);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\heater.wav');
                 audioPlayer=audioplayer(audio,fs);
            end

            function spinnerCallback(~,event)
                tempLabel.Text=['Set temp to: ',num2str(tempSpinner.Value)];
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.mp3');
                sound(y,Fs);
            end
            function setButtonCallback(~,event)
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\pick.wav');
                sound(y,Fs);
                delete(heatingFig);
                start(heatTimer);
                play(audioPlayer);
            end
            function timerCallback(~,event)    
               alertFig = uifigure('Name', 'Heating Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text','Bathroom has heated!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\ding.wav');
                sound(y,Fs);
            end
            
        end

        % Value changed function: WashingMachineSwitch
        function WashingMachineSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            

            if app.WashingMachineSwitch.Value=='1'   
                MachineFig = uifigure('Name', 'Washing Machine', 'Position', [180, 140, 500, 400]);
                uiimage(MachineFig, 'ImageSource', 'washing_machine.jpg', 'Position', [-195, -337, 900, 800] );
                uibutton(MachineFig, 'BackgroundColor', 'w', 'FontColor', 'k', 'Position', [184, 309, 35, 20], 'text', 'Start', ...
                    'VerticalAlignment', 'center', 'ButtonPushedFcn', @startButtonCallback); 
                uidropdown(MachineFig, 'Items', {'Cotton', 'Delicates', 'Duvet', 'Easy Care'}, 'Position', [270, 309, 70, 20], ...
                    'ValueChangedFcn', @dropdownCallback);
                %uilabel(MachineFig, 'Text', 'pls work', 'Position', [410, 360, 35, 20], 'BackgroundColor', 'k', 'FontColor', 'w');
                machineTimer=timer('TimerFcn',@timerCallback,'StartDelay',8);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\washing machine.wav');
                audioPlayer=audioplayer(audio,fs);
            end 

            function startButtonCallback(~, event)
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\beep sound.wav');
                sound(y,Fs);
                delete(MachineFig);
                start(machineTimer);
                play(audioPlayer);
            end 

            function timerCallback(~, event)
               alertFig = uifigure('Name', 'Washing machine Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text', 'Your laundry is all clean!', 'FontSize', 14, 'Position', [10, 40, 180, 20], 'HorizontalAlignment', 'center');
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\LG Washing machine tune.wav');
                sound(y,Fs);
            end
        end

        % Value changed function: DryerSwitch
        function DryerSwitchValueChanged(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\Light Switch Sound Effect.wav');
            sound(y,Fs);
            
            if app.DryerSwitch.Value=='1'
                DryerFig=uifigure('Name', 'Dryer', 'Position', [180, 140, 500, 400]);
                uiimage(DryerFig, 'ImageSource', 'dryer.jpg', 'Position', [-194, -390, 900, 800] );
                uibutton(DryerFig, 'BackgroundColor', 'w', 'FontColor', 'k', 'Position', [405, 305, 35, 20], ...
                    'text', 'Start', 'VerticalAlignment', 'center', 'ButtonPushedFcn', @startButtonCallback);
                dryerTimer=timer('TimerFcn',@timerCallback,'StartDelay',20);
                [audio,fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\dryer.wav');
                audioPlayer=audioplayer(audio,fs);
            end

            function startButtonCallback(~, event)
               [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\beep sound.wav');
                sound(y,Fs);
                delete(DryerFig);
                start(dryerTimer);
                play(audioPlayer);
            end 

            function timerCallback(~, event)
               alertFig = uifigure('Name', 'Dryer Alert', 'Position', [300, 300, 200, 100]);
               uilabel(alertFig, 'Text', 'Your laundry is now dry!', 'FontSize', 14, 'Position', [10, 40, 180, 20], ...
                   'HorizontalAlignment', 'center');
                [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\LG Washing machine tune.wav');
                sound(y,Fs);
            end
        end

        % Button pushed function: return5
        function return5ButtonPushed(app, event)
            [y,Fs]=audioread('C:\Users\User\Desktop\matlab_project\audio\click.wav');
            sound(y,Fs);
            
            app.bathroomBackgr.Visible='off';
            app.bathroom4.Visible='off';
            app.bathroom3.Visible='off';
            app.bathroom2.Visible='off';
            app.bathroom1.Visible='off';
            app.TabBathroomLight.Visible='off';
            app.TabMirrorLight.Visible='off';
            app.TabBathroomHeating.Visible='off';
            app.TabWashingMachine.Visible='off';
            app.TabDryer.Visible='off';
            app.BathroomLightSwitch.Visible='off';
            app.MirrorLightSwitch.Visible='off';
            app.BathroomHeatingSwitch.Visible='off';
            app.WashingMachineSwitch.Visible='off';
            app.DryerSwitch.Visible='off';
            app.BathroomLightIcon.Visible='off';
            app.BathroomHeatingIcon.Visible='off';
            app.MirrorLightIcon.Visible='off';
            app.WashingMachineIcon.Visible='off';
            app.DryerIcon.Visible='off';
            app.BathroomLightLable.Visible='off';
            app.BathroomHeatingLable.Visible='off';
            app.MirrorLightLable.Visible='off';
            app.WashingMachineLable.Visible='off';
            app.DryerLable.Visible='off';
            app.return5.Visible='off';
            app.StartButton.Visible='off';
            app.TourButton.Visible='off';
            app.HomeFlowNavigatorLabel.Visible='off';
            app.secretmessageeLabel.Visible='off';
            app.HomeBackgroud.Visible='off';
            app.roof.Visible='off';

            app.background2.Visible='on';
            app.house_layout.Visible='on';
            app.PickaroomyouwishtonavigateLabel.Visible='on';
            app.Bedroombutton.Visible='on';
            app.LivingroomButton.Visible='on';
            app.BathroomButton.Visible='on';
            app.KitchenButton.Visible='on';
            app.HallwayButton.Visible='on';
            app.return2home.Visible='on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.HeatingLabel = uilabel(app.UIFigure);
            app.HeatingLabel.Text = 'Heating';
            app.TVLabel = uilabel(app.UIFigure);
            app.TVLabel.Text = 'TV';
            app.bathroomBackgr = uiimage(app.UIFigure);
            app.bathroomBackgr.ImageSource = fullfile(pathToMLAPP, 'background3.jpg');
            app.bathroom4 = uiimage(app.UIFigure);
            app.bathroom4.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bathroom_bothON.jpg');
            app.bathroom3 = uiimage(app.UIFigure);
            app.bathroom3.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bathroom_mirror lightON.jpg');
            app.bathroom2 = uiimage(app.UIFigure);
            app.bathroom2.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bathroom_lightON.jpg');
            app.bathroom1 = uiimage(app.UIFigure);
            app.bathroom1.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bathroom.jpg');
            app.TabBathroomLight = uiimage(app.UIFigure);
            app.TabBathroomLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabMirrorLight = uiimage(app.UIFigure);
            app.TabMirrorLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabBathroomHeating = uiimage(app.UIFigure);
            app.TabBathroomHeating.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabWashingMachine = uiimage(app.UIFigure);
            app.TabWashingMachine.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabDryer = uiimage(app.UIFigure);
            app.TabDryer.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.BathroomLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.BathroomLightSwitch.ItemsData = {'0', '1'};
            app.BathroomLightSwitch.ValueChangedFcn = createCallbackFcn(app, @BathroomLightSwitchValueChanged, true);
            app.BathroomLightSwitch.Value = '0';
            app.MirrorLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.MirrorLightSwitch.ItemsData = {'0', '1'};
            app.MirrorLightSwitch.ValueChangedFcn = createCallbackFcn(app, @MirrorLightSwitchValueChanged, true);
            app.MirrorLightSwitch.Value = '0';
            app.BathroomHeatingSwitch = uiswitch(app.UIFigure, 'slider');
            app.BathroomHeatingSwitch.ItemsData = {'0', '1'};
            app.BathroomHeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @BathroomHeatingSwitchValueChanged, true);
            app.BathroomHeatingSwitch.Value = '0';
            app.WashingMachineSwitch = uiswitch(app.UIFigure, 'slider');
            app.WashingMachineSwitch.ItemsData = {'0', '1'};
            app.WashingMachineSwitch.ValueChangedFcn = createCallbackFcn(app, @WashingMachineSwitchValueChanged, true);
            app.WashingMachineSwitch.Value = '0';
            app.DryerSwitch = uiswitch(app.UIFigure, 'slider');
            app.DryerSwitch.ItemsData = {'0', '1'};
            app.DryerSwitch.ValueChangedFcn = createCallbackFcn(app, @DryerSwitchValueChanged, true);
            app.DryerSwitch.Value = '0';
            app.BathroomLightIcon = uiimage(app.UIFigure);
            app.BathroomLightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'light.png');
            app.MirrorLightIcon = uiimage(app.UIFigure);
            app.MirrorLightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'mirror.png');
            app.BathroomHeatingIcon = uiimage(app.UIFigure);
            app.BathroomHeatingIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'heating.png');
            app.WashingMachineIcon = uiimage(app.UIFigure);
            app.WashingMachineIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'washing machine.png');
            app.DryerIcon = uiimage(app.UIFigure);
            app.DryerIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'dryer.png');
            app.BathroomLightLable = uilabel(app.UIFigure);
            app.BathroomLightLable.Text = 'Light';
            app.MirrorLightLable = uilabel(app.UIFigure);
            app.MirrorLightLable.Text = 'Mirror light';
            app.BathroomHeatingLable = uilabel(app.UIFigure);
            app.BathroomHeatingLable.Text = 'heating';
            app.WashingMachineLable = uilabel(app.UIFigure);
            app.WashingMachineLable.Text = 'washer';
            app.DryerLable = uilabel(app.UIFigure);
            app.DryerLable.Text = 'dryer';
            app.return5 = uibutton(app.UIFigure, 'push');
            app.return5.ButtonPushedFcn = createCallbackFcn(app, @return5ButtonPushed, true);
            app.return5.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return5.Tooltip = {'Return to layout'};
            app.return5.Text = 'Back';
            app.hallwayBackgr = uiimage(app.UIFigure);
            app.hallwayBackgr.ImageSource = fullfile(pathToMLAPP, 'background3.jpg');
            app.hallway2 = uiimage(app.UIFigure);
            app.hallway2.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'hallwayON.jpg');
            app.hallway1 = uiimage(app.UIFigure);
            app.hallway1.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'hallway.jpg');
            app.TabHallwayLight = uiimage(app.UIFigure);
            app.TabHallwayLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabHallwayHeating = uiimage(app.UIFigure);
            app.TabHallwayHeating.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabLock = uiimage(app.UIFigure);
            app.TabLock.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.HallwayLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.HallwayLightSwitch.ItemsData = {'0', '1'};
            app.HallwayLightSwitch.ValueChangedFcn = createCallbackFcn(app, @HallwayLightSwitchValueChanged, true);
            app.HallwayLightSwitch.Value = '0';
            app.HallwayHeatingSwitch = uiswitch(app.UIFigure, 'slider');
            app.HallwayHeatingSwitch.ItemsData = {'0', '1'};
            app.HallwayHeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @HallwayHeatingSwitchValueChanged, true);
            app.HallwayHeatingSwitch.Value = '0';
            app.LockSwitch = uiswitch(app.UIFigure, 'slider');
            app.LockSwitch.ItemsData = {'0', '1'};
            app.LockSwitch.ValueChangedFcn = createCallbackFcn(app, @LockSwitchValueChanged, true);
            app.LockSwitch.Value = '0';
            app.HallwayLightIcon = uiimage(app.UIFigure);
            app.HallwayLightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'light.png');
            app.HallwayHeatingIcon = uiimage(app.UIFigure);
            app.HallwayHeatingIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'heating.png');
            app.LockIcon = uiimage(app.UIFigure);
            app.LockIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'lock.png');
            app.HallwayLightLabel = uilabel(app.UIFigure);
            app.HallwayLightLabel.Text = 'Light';
            app.HallwayHeatingLable = uilabel(app.UIFigure);
            app.HallwayHeatingLable.Text = 'Heating';
            app.LockLable = uilabel(app.UIFigure);
            app.LockLable.Text = 'lock';
            app.return4 = uibutton(app.UIFigure, 'push');
            app.return4.ButtonPushedFcn = createCallbackFcn(app, @return4ButtonPushed, true);
            app.return4.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return4.Tooltip = {'Return to layout'};
            app.return4.Text = 'Back';
            app.unlocked = uiimage(app.UIFigure);
            app.unlocked.ImageSource = fullfile(pathToMLAPP, 'room icons', 'unlocked.png');
            app.locked = uiimage(app.UIFigure);
            app.locked.ImageSource = fullfile(pathToMLAPP, 'room icons', 'locked.png');
            app.bedroomBackgr = uiimage(app.UIFigure);
            app.bedroomBackgr.ImageSource = fullfile(pathToMLAPP, 'background3.jpg');
            app.bedroom4 = uiimage(app.UIFigure);
            app.bedroom4.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bedroom_bothON.jpg');
            app.bedroom3 = uiimage(app.UIFigure);
            app.bedroom3.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bedroom_lampON.jpg');
            app.bedroom2 = uiimage(app.UIFigure);
            app.bedroom2.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bedroom_chandelierON.jpg');
            app.bedroom1 = uiimage(app.UIFigure);
            app.bedroom1.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'bedroom.jpg');
            app.TabBedroomLight = uiimage(app.UIFigure);
            app.TabBedroomLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabBedroomLamp = uiimage(app.UIFigure);
            app.TabBedroomLamp.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabBedroomHeating = uiimage(app.UIFigure);
            app.TabBedroomHeating.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabRadio = uiimage(app.UIFigure);
            app.TabRadio.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.BedroomLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.BedroomLightSwitch.ItemsData = {'0', '1'};
            app.BedroomLightSwitch.ValueChangedFcn = createCallbackFcn(app, @BedroomLightSwitchValueChanged, true);
            app.BedroomLightSwitch.Value = '0';
            app.BedroomLampSwitch = uiswitch(app.UIFigure, 'slider');
            app.BedroomLampSwitch.ItemsData = {'0', '1'};
            app.BedroomLampSwitch.ValueChangedFcn = createCallbackFcn(app, @BedroomLampSwitchValueChanged, true);
            app.BedroomLampSwitch.Value = '0';
            app.BedroomHeatingSwitch = uiswitch(app.UIFigure, 'slider');
            app.BedroomHeatingSwitch.ItemsData = {'0', '1'};
            app.BedroomHeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @BedroomHeatingSwitchValueChanged, true);
            app.BedroomHeatingSwitch.Value = '0';
            app.BedroomLightIcon = uiimage(app.UIFigure);
            app.BedroomLightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'light.png');
            app.BedroomLampIcon = uiimage(app.UIFigure);
            app.BedroomLampIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'lamp.png');
            app.BedroomHeatingIcon = uiimage(app.UIFigure);
            app.BedroomHeatingIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'heating.png');
            app.BedroomRadioIcon = uiimage(app.UIFigure);
            app.BedroomRadioIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'radio.png');
            app.BedroomLightLabel = uilabel(app.UIFigure);
            app.BedroomLightLabel.Text = 'Light';
            app.BedroomLampLabel = uilabel(app.UIFigure);
            app.BedroomLampLabel.Text = 'Lamp';
            app.BedroomHeatingLabel = uilabel(app.UIFigure);
            app.BedroomHeatingLabel.Text = 'Heating';
            app.BedroomRadioLabel = uilabel(app.UIFigure);
            app.BedroomRadioLabel.Text = 'Radio';
            app.return3 = uibutton(app.UIFigure, 'push');
            app.return3.ButtonPushedFcn = createCallbackFcn(app, @return3ButtonPushed, true);
            app.return3.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return3.Tooltip = {'Return to layout'};
            app.return3.Text = 'Back';
            app.DropDownRadio = uidropdown(app.UIFigure);
            app.DropDownRadio.Items = {'Off', 'Falestini', 'AdDuha', 'MaherZain'};
            app.DropDownRadio.ItemsData = {'Off', 'Falestini', 'AdDuha', 'MaherZain'};
            app.DropDownRadio.ValueChangedFcn = createCallbackFcn(app, @DropDownRadioValueChanged, true);
            app.DropDownRadio.Value = 'Off';
            app.kitchenBackgr = uiimage(app.UIFigure);
            app.kitchenBackgr.ImageSource = fullfile(pathToMLAPP, 'background3.jpg');
            app.kitchen4 = uiimage(app.UIFigure);
            app.kitchen4.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'kitchen_bothON.jpg');
            app.kitchen3 = uiimage(app.UIFigure);
            app.kitchen3.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'kitchen_lightON.jpg');
            app.kitchen2 = uiimage(app.UIFigure);
            app.kitchen2.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'kitchen_chandelierON.jpg');
            app.kitchen1 = uiimage(app.UIFigure);
            app.kitchen1.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'kitchen.jpg');
            app.tabChandelier = uiimage(app.UIFigure);
            app.tabChandelier.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.tabKitchenLight = uiimage(app.UIFigure);
            app.tabKitchenLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabKitchenHeating = uiimage(app.UIFigure);
            app.TabKitchenHeating.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.TabOven = uiimage(app.UIFigure);
            app.TabOven.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.return2 = uibutton(app.UIFigure, 'push');
            app.return2.ButtonPushedFcn = createCallbackFcn(app, @return2ButtonPushed, true);
            app.return2.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return2.Tooltip = {'Return to layout'};
            app.return2.Text = 'Back';
            app.ChandelierLabel = uilabel(app.UIFigure);
            app.ChandelierLabel.Text = 'Chandelier';
            app.ChandelierSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChandelierSwitch.ItemsData = {'0', '1'};
            app.ChandelierSwitch.ValueChangedFcn = createCallbackFcn(app, @ChandelierSwitchValueChanged, true);
            app.ChandelierSwitch.Value = '0';
            app.KitchenLightLable = uilabel(app.UIFigure);
            app.KitchenLightLable.Text = 'Light';
            app.KitchenLightSwitch = uiswitch(app.UIFigure, 'slider');
            app.KitchenLightSwitch.ItemsData = {'0', '1'};
            app.KitchenLightSwitch.ValueChangedFcn = createCallbackFcn(app, @KitchenLightSwitchValueChanged, true);
            app.KitchenLightSwitch.Value = '0';
            app.KitchenHeatingLable = uilabel(app.UIFigure);
            app.KitchenHeatingLable.Text = 'Heating';
            app.KitchenHeatingSwitch = uiswitch(app.UIFigure, 'slider');
            app.KitchenHeatingSwitch.ItemsData = {'0', '1'};
            app.KitchenHeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @KitchenHeatingSwitchValueChanged, true);
            app.KitchenHeatingSwitch.Value = '0';
            app.OvenLable = uilabel(app.UIFigure);
            app.OvenLable.Text = 'Oven';
            app.OvenSwitch = uiswitch(app.UIFigure, 'slider');
            app.OvenSwitch.ItemsData = {'0', '1'};
            app.OvenSwitch.ValueChangedFcn = createCallbackFcn(app, @OvenSwitchValueChanged, true);
            app.OvenSwitch.Value = '0';
            app.ChandelierIcon = uiimage(app.UIFigure);
            app.ChandelierIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'chandelier.png');
            app.KitchenLightIcon = uiimage(app.UIFigure);
            app.KitchenLightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'light.png');
            app.KitchenHeatingIcon = uiimage(app.UIFigure);
            app.KitchenHeatingIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'heating.png');
            app.OvenIcon = uiimage(app.UIFigure);
            app.OvenIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'oven.png');
            app.livingroomBackgr = uiimage(app.UIFigure);
            app.livingroomBackgr.ImageSource = fullfile(pathToMLAPP, 'background3.jpg');
            app.livingroom4 = uiimage(app.UIFigure);
            app.livingroom4.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'living_room_bothON.jpg');
            app.livingroom3 = uiimage(app.UIFigure);
            app.livingroom3.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'living_room_lampON.jpg');
            app.livingroom2 = uiimage(app.UIFigure);
            app.livingroom2.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'living_room_lightON.jpg');
            app.livingroom1 = uiimage(app.UIFigure);
            app.livingroom1.ImageSource = fullfile(pathToMLAPP, 'room pictures', 'living_room.jpg');
            app.tabLight = uiimage(app.UIFigure);
            app.tabLight.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.lightIcon = uiimage(app.UIFigure);
            app.lightIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'light.png');
            app.tabLamp = uiimage(app.UIFigure);
            app.tabLamp.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.tabHeating = uiimage(app.UIFigure);
            app.tabHeating.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.tabTV = uiimage(app.UIFigure);
            app.tabTV.ImageSource = fullfile(pathToMLAPP, 'tab.png');
            app.LightSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightSwitch.ItemsData = {'0', '1'};
            app.LightSwitch.ValueChangedFcn = createCallbackFcn(app, @LightSwitchValueChanged, true);
            app.LightSwitch.Value = '0';
            app.LampSwitch = uiswitch(app.UIFigure, 'slider');
            app.LampSwitch.ItemsData = {'0', '1'};
            app.LampSwitch.ValueChangedFcn = createCallbackFcn(app, @LampSwitchValueChanged, true);
            app.LampSwitch.Value = '0';
            app.HeatingSwitch = uiswitch(app.UIFigure, 'slider');
            app.HeatingSwitch.ItemsData = {'0', '1'};
            app.HeatingSwitch.ValueChangedFcn = createCallbackFcn(app, @HeatingSwitchValueChanged, true);
            app.HeatingSwitch.Value = '0';
            app.TVSwitch = uiswitch(app.UIFigure, 'slider');
            app.TVSwitch.ItemsData = {'0', '1'};
            app.TVSwitch.ValueChangedFcn = createCallbackFcn(app, @TVSwitchValueChanged, true);
            app.TVSwitch.Value = '0';
            app.heatingIcon = uiimage(app.UIFigure);
            app.heatingIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'heating.png');
            app.TVIcon = uiimage(app.UIFigure);
            app.TVIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'TV.png');
            app.lampIcon = uiimage(app.UIFigure);
            app.lampIcon.ImageSource = fullfile(pathToMLAPP, 'room icons', 'lamp.png');
            app.return1 = uibutton(app.UIFigure, 'push');
            app.return1.ButtonPushedFcn = createCallbackFcn(app, @return1ButtonPushed, true);
            app.return1.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return1.Tooltip = {'Return to layout'};
            app.return1.Text = 'Back';
            app.lightlabel = uilabel(app.UIFigure);
            app.lightlabel.Text = 'Light';
            app.lamplabel = uilabel(app.UIFigure);
            app.lamplabel.Text = 'Lamp';
            app.heatinglabel = uilabel(app.UIFigure);
            app.heatinglabel.Text = 'Heating';
            app.tvlabel = uilabel(app.UIFigure);
            app.tvlabel.Text = 'TV';
            app.background2 = uiimage(app.UIFigure);
            app.background2.ImageSource = fullfile(pathToMLAPP, 'background2.jpg');
            app.house_layout = uiimage(app.UIFigure);
            app.house_layout.ImageSource = fullfile(pathToMLAPP, 'house_layout.png');
            app.PickaroomyouwishtonavigateLabel = uilabel(app.UIFigure);
            app.PickaroomyouwishtonavigateLabel.Text = 'Pick a room you wish to navigate';
            app.Bedroombutton = uibutton(app.UIFigure, 'push');
            app.Bedroombutton.ButtonPushedFcn = createCallbackFcn(app, @BedroombuttonButtonPushed, true);
            app.Bedroombutton.Icon = fullfile(pathToMLAPP, 'room icons', 'room.png');
            app.Bedroombutton.Tooltip = {'Bedroom'};
            app.Bedroombutton.Text = '';
            app.LivingroomButton = uibutton(app.UIFigure, 'push');
            app.LivingroomButton.ButtonPushedFcn = createCallbackFcn(app, @LivingroomButtonPushed, true);
            app.LivingroomButton.Icon = fullfile(pathToMLAPP, 'room icons', 'relax.png');
            app.LivingroomButton.Tooltip = {'Living room'};
            app.LivingroomButton.Text = '';
            app.HallwayButton = uibutton(app.UIFigure, 'push');
            app.HallwayButton.ButtonPushedFcn = createCallbackFcn(app, @HallwayButtonPushed, true);
            app.HallwayButton.Icon = fullfile(pathToMLAPP, 'room icons', 'wardrobe.png');
            app.HallwayButton.Tooltip = {'Hallway'};
            app.HallwayButton.Text = '';
            app.BathroomButton = uibutton(app.UIFigure, 'push');
            app.BathroomButton.ButtonPushedFcn = createCallbackFcn(app, @BathroomButtonPushed, true);
            app.BathroomButton.Icon = fullfile(pathToMLAPP, 'room icons', 'apartment.png');
            app.BathroomButton.Tooltip = {'Bathroom'};
            app.BathroomButton.Text = '';
            app.KitchenButton = uibutton(app.UIFigure, 'push');
            app.KitchenButton.ButtonPushedFcn = createCallbackFcn(app, @KitchenButtonPushed, true);
            app.KitchenButton.Icon = fullfile(pathToMLAPP, 'room icons', 'fridge.png');
            app.KitchenButton.Tooltip = {'Kitchen'};
            app.KitchenButton.Text = '';
            app.return2home = uibutton(app.UIFigure, 'push');
            app.return2home.ButtonPushedFcn = createCallbackFcn(app, @return2homeButtonPushed, true);
            app.return2home.Icon = fullfile(pathToMLAPP, 'room icons', 'return.png');
            app.return2home.Tooltip = {'Return to START'};
            app.return2home.Text = 'Back';
            app.tourPanel = uipanel(app.UIFigure);
            app.tvchannel = uiaxes(app.tourPanel);
            app.tvchannel.XTick = [];
            app.tvchannel.YTick = [];
            app.tvchannel.ZTick = [];
            app.imageAxes = uiaxes(app.tourPanel);
            app.imageAxes.XTick = [];
            app.imageAxes.YTick = [];
            app.imageAxes.ZTick = [];
            app.BackButton = uibutton(app.tourPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.HomeBackgroud = uibutton(app.UIFigure, 'push');
            app.HomeBackgroud.Icon = fullfile(pathToMLAPP, 'background.jpg');
            app.HomeBackgroud.Text = '';
            app.secretmessageeLabel = uilabel(app.UIFigure);
            app.secretmessageeLabel.Text = 'secret messagee';
            app.HomeFlowNavigatorLabel = uilabel(app.UIFigure);
            app.HomeFlowNavigatorLabel.Text = {'HomeFlow '; 'Navigator'};
            app.roof = uiimage(app.UIFigure);
            app.roof.ImageSource = fullfile(pathToMLAPP, 'room icons', 'roof2.png');
            app.TourButton = uibutton(app.UIFigure, 'push');
            app.TourButton.ButtonPushedFcn = createCallbackFcn(app, @TourButtonPushed, true);
            app.TourButton.Tooltip = {'Take a tour of the house!'};
            app.TourButton.Text = 'Tour';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Click to go to the house layout'};
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end